classdef opto_analyser_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        ImageIOLabel                matlab.ui.control.Label
        ReleasefileEditFieldLabel   matlab.ui.control.Label
        ReleasefileEditField        matlab.ui.control.EditField
        RecoveryfileEditFieldLabel  matlab.ui.control.Label
        RecoveryfileEditField       matlab.ui.control.EditField
        CellNameEditFieldLabel      matlab.ui.control.Label
        CellNameEditField           matlab.ui.control.EditField
        NoofintervalsLabel          matlab.ui.control.Label
        OKButton                    matlab.ui.control.StateButton
        ConfirmSelectionLabel       matlab.ui.control.Label
        OutputinformationLabel      matlab.ui.control.Label
        TextArea                    matlab.ui.control.TextArea
        IntervalsselectedLabel      matlab.ui.control.Label
        CenterPanel                 matlab.ui.container.Panel
        TabGroup                    matlab.ui.container.TabGroup
        ChannelselectionTab         matlab.ui.container.Tab
        Channel1Label               matlab.ui.control.Label
        FrameSliderLabel            matlab.ui.control.Label
        FrameSlider                 matlab.ui.control.Slider
        Channel1ButtonGroup         matlab.ui.container.ButtonGroup
        NoneButton_5                matlab.ui.control.RadioButton
        MitochondriaButton          matlab.ui.control.RadioButton
        CytoplasmButton             matlab.ui.control.RadioButton
        NucleusButton               matlab.ui.control.RadioButton
        Channel2ButtonGroup         matlab.ui.container.ButtonGroup
        NoneButton_6                matlab.ui.control.RadioButton
        MitochondriaButton_2        matlab.ui.control.RadioButton
        CytoplasmButton_2           matlab.ui.control.RadioButton
        NucleusButton_2             matlab.ui.control.RadioButton
        Channel3ButtonGroup         matlab.ui.container.ButtonGroup
        NoneButton_7                matlab.ui.control.RadioButton
        MitochondriaButton_3        matlab.ui.control.RadioButton
        CytoplasmButton_3           matlab.ui.control.RadioButton
        NucleusButton_3             matlab.ui.control.RadioButton
        Channel4ButtonGroup         matlab.ui.container.ButtonGroup
        NoneButton_8                matlab.ui.control.RadioButton
        MitochondriaButton_4        matlab.ui.control.RadioButton
        CytoplasmButton_4           matlab.ui.control.RadioButton
        NucleusButton_4             matlab.ui.control.RadioButton
        AcceptChannelsandCropImageButton  matlab.ui.control.StateButton
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes4                     matlab.ui.control.UIAxes
        AcceptCropButton            matlab.ui.control.StateButton
        Channel2Label               matlab.ui.control.Label
        Channel3Label               matlab.ui.control.Label
        Channel4Label               matlab.ui.control.Label
        CompartmentviewingpercentilesTab  matlab.ui.container.Tab
        LoadNextIterationButton     matlab.ui.control.StateButton
        UIAxes7                     matlab.ui.control.UIAxes
        UIAxes7_2                   matlab.ui.control.UIAxes
        PercentageSliderLabel       matlab.ui.control.Label
        PercentageSlider            matlab.ui.control.Slider
        PercentageSlider_2Label     matlab.ui.control.Label
        PercentageSlider_2          matlab.ui.control.Slider
        NucleusLabel                matlab.ui.control.Label
        MitochondriaLabel           matlab.ui.control.Label
        AcceptPercentilesforImageViewingButton  matlab.ui.control.StateButton
        FramewindowSliderLabel      matlab.ui.control.Label
        FramewindowSlider           matlab.ui.control.Slider
        FramewindowSlider_2Label    matlab.ui.control.Label
        FramewindowSlider_2         matlab.ui.control.Slider
        CellboundarycropTab         matlab.ui.container.Tab
        UIAxes5                     matlab.ui.control.UIAxes
        UIAxes6                     matlab.ui.control.UIAxes
        UIAxes6_2                   matlab.ui.control.UIAxes
        UIAxes6_3                   matlab.ui.control.UIAxes
        ImageSelectionButtonGroup   matlab.ui.container.ButtonGroup
        Image1Button                matlab.ui.control.ToggleButton
        Image2Button                matlab.ui.control.ToggleButton
        Image3Button                matlab.ui.control.ToggleButton
        ConfirmcropButton           matlab.ui.control.StateButton
        CellthresholdingTab         matlab.ui.container.Tab
        CompartmentthresholdingTab  matlab.ui.container.Tab
        RightPanel                  matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        lsm_flag = 1 % flags whether files are both of lsm type (=1) or not (=0).
        error_counter = 0;
        intervals = [];
        
        LSMRel
        LSMRec
        TRel
        TRec
        length
        channels
        u
        v
        interval_stage
        interval_counter
        
        nuclear_channel
        mitochondrial_channel
        protein_channel
       
        ImgCRelAll
        ImgCRecAll
        ImgCAll
        IntensityAll
        CellRel
      
        
        vertstart
        vertend
        horzstart
        horzend
        
        app_stage=0 % Only allows app functionality to be implemented in the correct order
        
        ImgNRel
        ImgCRel
        ImgMRel
        
        ImgNRec
        ImgCRec
        ImgMRec


        AllCellRel
        AllCellRec
        AllCellRelMax
        AllCellRecMax
        AllCellMax
        
        ImgN
        ImgC1
        ImgM
        
        regions
        Midpoints
        
        AllCellRegionMax
        RegionNMax
        RegionMMax
        RegionC1Max
        MedianRegion
        
        nuclear_prctile
        mito_prctile
        
        nuclear_region_index
        mito_region_index
        
        nuclear_slider_prctile_flag
        mito_slider_prctile_flag
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: OKButton
        function OKButtonValueChanged(app, event)
            value = app.OKButton.Value;
            app.lsm_flag = 1;
            app.OutputinformationLabel.BackgroundColor = 'none';
            app.error_counter = 0;
            app.OutputinformationLabel.Text = 'Error Messages';
            info_label = app.OutputinformationLabel.Text;
            release_file=app.ReleasefileEditField.Value;
            recovery_file=app.RecoveryfileEditField.Value;
            if (isempty(release_file)) || (isempty(release_file)) || (length(release_file) < 3) || (length(recovery_file) < 3)
               lsm_type_error = ['Error. Release and recovery' newline 'filename(s) cannot be empty.' newline 'There may be additional errors.'];
               app.lsm_flag = 0;
               app.error_counter = app.error_counter +1;
               info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
               app.OutputinformationLabel.Text = info_label;
               app.OutputinformationLabel.BackgroundColor = 'k';
               return
            end
            if(strcmp(release_file(end-2:end),'lsm')==0)||(strcmp(recovery_file(end-2:end),'lsm')==0)
               lsm_type_error = ['Error. Image files must' newline 'be of lsm type.'];
               app.lsm_flag = 0;
               app.error_counter = app.error_counter +1;
               info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
               app.OutputinformationLabel.Text = info_label;
               app.OutputinformationLabel.BackgroundColor = 'k';
            end
            
            if (isfile(release_file) == 0) || (isfile(recovery_file) == 0)
                lsm_type_error = ['Error. At least one image' newline 'file does not exist.'];
                app.lsm_flag = 0;
                app.error_counter = app.error_counter +1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                app.OutputinformationLabel.BackgroundColor = 'k';
            end
            if (isempty(app.CellNameEditField.Value))
               lsm_type_error = ['Error. Cell Name cannot be empty.' newline 'There may be additional errors.'];
               app.lsm_flag = 0;
               app.error_counter = app.error_counter +1;
               info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
               app.OutputinformationLabel.Text = info_label;
               app.OutputinformationLabel.BackgroundColor = 'k';
               return
            end
            if (isempty(regexp(app.CellNameEditField.Value,'\W')) == 0)
                lsm_type_error = ['Error. Cell name cannot include' newline 'spaces or special characters.'];
                app.lsm_flag = 0;
                app.error_counter = app.error_counter +1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                app.OutputinformationLabel.BackgroundColor = 'k';
            end
           
           interval_string = app.TextArea.Value;
           interval_string = interval_string{1}; 
           if (isempty(interval_string)) 
               lsm_type_error = ['Error. No. of intervals cannot be empty.'];
               app.lsm_flag = 0;
               app.error_counter = app.error_counter +1;
               info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
               app.OutputinformationLabel.Text = info_label;
               app.OutputinformationLabel.BackgroundColor = 'k';
               return
            end
            app.IntervalsselectedLabel.Text = 'Intervals selected: ';
            app.intervals = [];
            print_intervals=1;
            value_split=strsplit(interval_string,',');
            for I=1:length(value_split)
                value_num=str2num(value_split{I});
                if(isempty(value_num))
                    lsm_type_error = ['Error. Intervals must be integers' newline 'greater than zero in' newline 'comma separated format.'];
                    app.lsm_flag = 0;
                    app.error_counter = app.error_counter +1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    app.OutputinformationLabel.BackgroundColor = 'k';
                    print_intervals=0;
                    break
                elseif (mod(value_num,1) ~= 0) ||  (value_num < 0)
                    lsm_type_error = ['Error. Intervals must be integers' newline 'greater than zero in' newline 'comma separated format.'];
                    app.lsm_flag = 0;
                    app.error_counter = app.error_counter +1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    app.OutputinformationLabel.BackgroundColor = 'k';
                    print_intervals=0;
                    break
                else
                    app.intervals = [app.intervals,value_num];    
                end
            end
            if(isempty(app.intervals) == 0) && (print_intervals == 1)
                app.intervals=unique(app.intervals);
                integer_string = sprintf('%.0f,' , app.intervals);
                integer_string = integer_string(1:end-1);% strip final comma
                app.IntervalsselectedLabel.Text=[app.IntervalsselectedLabel.Text newline integer_string];
            end
            if (app.lsm_flag ==1)
                [app.LSMRel, varargout] = lsmread(app.ReleasefileEditField.Value);
                [app.TRel,app.channels,~,app.u,app.v]=size(app.LSMRel);
                
                [app.LSMRec, varargout] = lsmread(app.RecoveryfileEditField.Value);
                [app.TRec,~,~,u_rec,v_rec]=size(app.LSMRec);
                
                app.length = app.TRel + app.TRec;

        
%                 app.interval_stage=0;
%                 app.interval_counter=0;
                %[time, app.channel, dim, row, col] = size(app.LSMRel);
                
                if app.channels~=3 && app.channels~=4
                    lsm_type_error = ['Error. No. of channels must be 3 or 4.'];
                    app.lsm_flag = 0;
                    app.error_counter = app.error_counter +1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    app.OutputinformationLabel.BackgroundColor = 'k';
                elseif u_rec ~= app.u || v_rec ~= app.v
                    lsm_type_error = ['Error. Spatial dimensions of' newline 'release and recovery phases' newline 'should be the same.'];
                    app.lsm_flag = 0;
                    app.error_counter = app.error_counter +1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    app.OutputinformationLabel.BackgroundColor = 'k';
                else
                    ch1(:, :) = app.LSMRel(1, 1, 1, :, :);
                    ch2(:, :) = app.LSMRel(1, 2, 1, :, :);
                    ch3(:, :) = app.LSMRel(1, 3, 1, :, :);
                    
                    title(app.UIAxes, []);
                    xlabel(app.UIAxes, []);
                    ylabel(app.UIAxes, []);
                    app.UIAxes.XAxis.TickLabels = {};
                    app.UIAxes.YAxis.TickLabels = {};
                    % Display image and stretch to fill axes
                    I = imagesc(ch1, 'Parent', app.UIAxes, ...
                        'XData', [1 app.UIAxes.Position(3)], ...
                        'YData', [1 app.UIAxes.Position(4)]);
                    % Set limits of axes
                    app.UIAxes.XLim = [0 I.XData(2)];
                    app.UIAxes.YLim = [0 I.YData(2)];
                    axis(app.UIAxes, 'equal');
                    
                    title(app.UIAxes2, []);
                    xlabel(app.UIAxes2, []);
                    ylabel(app.UIAxes2, []);
                    app.UIAxes2.XAxis.TickLabels = {};
                    app.UIAxes2.YAxis.TickLabels = {};
                    % Display image and stretch to fill axes
                    I = imagesc(ch2, 'Parent', app.UIAxes2, ...
                        'XData', [1 app.UIAxes2.Position(3)], ...
                        'YData', [1 app.UIAxes2.Position(4)]);
                    % Set limits of axes
                    app.UIAxes2.XLim = [0 I.XData(2)];
                    app.UIAxes2.YLim = [0 I.YData(2)];
                    axis(app.UIAxes2, 'equal');
                    
                    title(app.UIAxes3, []);
                    xlabel(app.UIAxes3, []);
                    ylabel(app.UIAxes3, []);
                    app.UIAxes3.XAxis.TickLabels = {};
                    app.UIAxes3.YAxis.TickLabels = {};
                    % Display image and stretch to fill axes
                    I = imagesc(ch3, 'Parent', app.UIAxes3, ...
                        'XData', [1 app.UIAxes3.Position(3)], ...
                        'YData', [1 app.UIAxes3.Position(4)]);
                    % Set limits of axes
                    app.UIAxes3.XLim = [0 I.XData(2)];
                    app.UIAxes3.YLim = [0 I.YData(2)];
                    axis(app.UIAxes3, 'equal');
                           
                    if app.channels == 4
                        ch4(:, :) = app.LSMRel(1, 4, 1, :, :);
                        title(app.UIAxes4, []);
                        xlabel(app.UIAxes4, []);
                        ylabel(app.UIAxes4, []);
                        app.UIAxes4.XAxis.TickLabels = {};
                        app.UIAxes4.YAxis.TickLabels = {};
                        % Display image and stretch to fill axes
                        I = imagesc(ch4, 'Parent', app.UIAxes4, ...
                            'XData', [1 app.UIAxes4.Position(3)], ...
                            'YData', [1 app.UIAxes4.Position(4)]);
                        % Set limits of axes
                        app.UIAxes4.XLim = [0 I.XData(2)];
                        app.UIAxes4.YLim = [0 I.YData(2)];
                        axis(app.UIAxes4, 'equal');
                    end
                    
                app.app_stage=1;
                app.OutputinformationLabel.BackgroundColor = 'none';
                end
                
            end
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            changingValue = uint8(min(max(round(event.Value),1),app.TRel));
            if app.app_stage ==1
                app.OutputinformationLabel.BackgroundColor = 'none';
                ch1(:, :) = app.LSMRel(changingValue, 1, 1, :, :);
                ch2(:, :) = app.LSMRel(changingValue, 2, 1, :, :);
                ch3(:, :) = app.LSMRel(changingValue, 3, 1, :, :);
                    
                title(app.UIAxes, []);
                xlabel(app.UIAxes, []);
                ylabel(app.UIAxes, []);
                app.UIAxes.XAxis.TickLabels = {};
                app.UIAxes.YAxis.TickLabels = {};
                % Display image and stretch to fill axes
                I = imagesc(ch1, 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)], ...
                    'YData', [1 app.UIAxes.Position(4)]);
                % Set limits of axes
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
                axis(app.UIAxes, 'equal');
                
                title(app.UIAxes2, []);
                xlabel(app.UIAxes2, []);
                ylabel(app.UIAxes2, []);
                app.UIAxes2.XAxis.TickLabels = {};
                app.UIAxes2.YAxis.TickLabels = {};
                % Display image and stretch to fill axes
                I = imagesc(ch2, 'Parent', app.UIAxes2, ...
                    'XData', [1 app.UIAxes2.Position(3)], ...
                    'YData', [1 app.UIAxes2.Position(4)]);
                % Set limits of axes
                app.UIAxes2.XLim = [0 I.XData(2)];
                app.UIAxes2.YLim = [0 I.YData(2)];
                axis(app.UIAxes2, 'equal');
                
                title(app.UIAxes3, []);
                xlabel(app.UIAxes3, []);
                ylabel(app.UIAxes3, []);
                app.UIAxes3.XAxis.TickLabels = {};
                app.UIAxes3.YAxis.TickLabels = {};
                % Display image and stretch to fill axes
                I = imagesc(ch3, 'Parent', app.UIAxes3, ...
                    'XData', [1 app.UIAxes3.Position(3)], ...
                    'YData', [1 app.UIAxes3.Position(4)]);
                % Set limits of axes
                app.UIAxes3.XLim = [0 I.XData(2)];
                app.UIAxes3.YLim = [0 I.YData(2)];
                axis(app.UIAxes3, 'equal');
                       
                if app.channels == 4
                    ch4(:, :) = app.LSMRel(changingValue, 4, 1, :, :);
                    title(app.UIAxes4, []);
                    xlabel(app.UIAxes4, []);
                    ylabel(app.UIAxes4, []);
                    app.UIAxes4.XAxis.TickLabels = {};
                    app.UIAxes4.YAxis.TickLabels = {};
                    % Display image and stretch to fill axes
                    I = imagesc(ch4, 'Parent', app.UIAxes4, ...
                        'XData', [1 app.UIAxes4.Position(3)], ...
                        'YData', [1 app.UIAxes4.Position(4)]);
                    % Set limits of axes
                    app.UIAxes4.XLim = [0 I.XData(2)];
                    app.UIAxes4.YLim = [0 I.YData(2)];
                    axis(app.UIAxes4, 'equal');
                end
                
            else
                info_label = 'Error Messages';
                lsm_type_error = ['Error. Complete I/O correctly.'];
                app.error_counter = 1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                app.OutputinformationLabel.BackgroundColor = 'k';
           
            end
            
        end

        % Value changed function: AcceptChannelsandCropImageButton
        function AcceptChannelsandCropImageButtonValueChanged(app, event)
            value = app.AcceptChannelsandCropImageButton.Value;
            if app.app_stage ==1
                app.lsm_flag = 1;
                app.OutputinformationLabel.BackgroundColor = 'none';
                app.error_counter = 0;
                app.OutputinformationLabel.Text = 'Error Messages';
                info_label = app.OutputinformationLabel.Text;
                if (app.NoneButton_8.Value == 0) && (app.channels == 3) 
                   lsm_type_error = ['Error. There are only three channels.' newline 'Channel 4 should be "None".'];
                   app.lsm_flag = 0;
                   app.error_counter = app.error_counter +1;
                   info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                   app.OutputinformationLabel.Text = info_label;
                   app.OutputinformationLabel.BackgroundColor = 'k';
                end
                
                total_nucleus_radio = app.NucleusButton.Value + app.NucleusButton_2.Value + app.NucleusButton_3.Value + app.NucleusButton_4.Value;
                total_mitochondria_radio = app.MitochondriaButton.Value + app.MitochondriaButton_2.Value + app.MitochondriaButton_3.Value + app.MitochondriaButton_4.Value;
                total_cytoplasm_radio = app.CytoplasmButton.Value + app.CytoplasmButton_2.Value + app.CytoplasmButton_3.Value + app.CytoplasmButton_4.Value;
                
                if total_cytoplasm_radio == 2
                   lsm_type_error = ['Error. The app can analyse only one protein' newline 'at a time at present'];
                   app.lsm_flag = 0;
                   app.error_counter = app.error_counter +1;
                   info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                   app.OutputinformationLabel.Text = info_label;
                   app.OutputinformationLabel.BackgroundColor = 'k';
                end
                
                
                if total_nucleus_radio ~= 1 || total_mitochondria_radio ~= 1 || (total_cytoplasm_radio ~= 1 && total_cytoplasm_radio ~= 2)
                   lsm_type_error = ['Error. There must be one allocated' newline 'protein channel, nuclear channel and' newline 'mitochondrial channel.'];
                   app.lsm_flag = 0;
                   app.error_counter = app.error_counter +1;
                   info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                   app.OutputinformationLabel.Text = info_label;
                   app.OutputinformationLabel.BackgroundColor = 'k';
                end
                
                if app.lsm_flag == 1
                    nucleus_location = [app.NucleusButton.Value, app.NucleusButton_2.Value, app.NucleusButton_3.Value, app.NucleusButton_4.Value];
                    protein_location = [app.CytoplasmButton.Value, app.CytoplasmButton_2.Value, app.CytoplasmButton_3.Value, app.CytoplasmButton_4.Value];
                    mitochondria_location = [app.MitochondriaButton.Value, app.MitochondriaButton_2.Value, app.MitochondriaButton_3.Value, app.MitochondriaButton_4.Value];
                    app.nuclear_channel = find(nucleus_location);
                    app.mitochondrial_channel = find(mitochondria_location);
                    app.protein_channel = find(protein_location);
                    
                    app.ImgCRelAll=zeros(app.u,app.v,app.TRel);
                    for I = 1:app.TRel
                        app.ImgCRelAll(:, :, I)=app.LSMRel(I, app.protein_channel, 1, :, :);
                    end
                    app.ImgCRecAll=zeros(app.u,app.v,app.TRec);
                    for I = 1:app.TRec
                        app.ImgCRecAll(:, :, I)=app.LSMRec(I, app.protein_channel, 1, :, :);
                    end
                    app.ImgCAll = cat(3, app.ImgCRelAll, app.ImgCRecAll);
                    app.IntensityAll = sum(sum(app.ImgCAll,1), 2);
                    app.CellRel(1:app.u, 1:app.v) = app.LSMRel(1,app.protein_channel,1,:,:);
    
                    crop_image = figure;
                    imagesc(app.CellRel);
                    iptsetpref('ImshowAxesVisible','off')
                    axis('equal');
                    %Draw a rectangle around image to get new image bounds
                    h = imrect;
                    BW = createMask(h);
                    delete(h);
                    delete(crop_image);
                    index = find(BW);
                    [y, x] = ind2sub(size(BW),index);
                    app.vertstart = min(y);
                    app.vertend = max(y);
                    app.horzstart = min(x);
                    app.horzend = max(x);
                    app.app_stage = 2;
                    app.OutputinformationLabel.BackgroundColor = 'none';
                end
                
            elseif app.app_stage ==0
                info_label = 'Error Messages';
                lsm_type_error = ['Error. Complete I/O correctly.'];
                app.error_counter = 1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                app.OutputinformationLabel.BackgroundColor = 'k';
            else
                info_label = 'Error Messages';
                lsm_type_error = ['Error. Image cropping is already complete.'];
                app.error_counter = 1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                app.OutputinformationLabel.BackgroundColor = 'k';
            end


        end

        % Value changed function: AcceptCropButton
        function AcceptCropButtonValueChanged(app, event)
            value = app.AcceptCropButton.Value;
             if app.app_stage ==2
                app.OutputinformationLabel.BackgroundColor = 'none';
                for I=1:app.TRel
                    app.ImgNRel(:,:,I) = app.LSMRel(I,app.nuclear_channel,1,app.vertstart:app.vertend,app.horzstart:app.horzend);
                    app.ImgCRel(:,:,I) = app.LSMRel(I,app.protein_channel,1,app.vertstart:app.vertend,app.horzstart:app.horzend);
                    app.ImgMRel(:,:,I) = app.LSMRel(I,app.mitochondrial_channel,1,app.vertstart:app.vertend,app.horzstart:app.horzend);
                end;
                for I=1:app.TRec
                    app.ImgNRec(:,:,I) = app.LSMRec(I,app.nuclear_channel,1,app.vertstart:app.vertend,app.horzstart:app.horzend);
                    app.ImgCRec(:,:,I) = app.LSMRec(I,app.protein_channel,1,app.vertstart:app.vertend,app.horzstart:app.horzend);
                    app.ImgMRec(:,:,I) = app.LSMRec(I,app.mitochondrial_channel,1,app.vertstart:app.vertend,app.horzstart:app.horzend);
                end;
                %Maximum of all release compartments (3d for time)
                app.AllCellRel = max(max(app.ImgNRel,app.ImgCRel),app.ImgMRel);
                %Maximum of all recovery compartments (3d for time)
                app.AllCellRec = max(max(app.ImgNRec,app.ImgCRec),app.ImgMRec);
                %2D maximum  of all release stages
                app.AllCellRelMax = max(app.AllCellRel,[],3);
                %2D maximum of all recovery stages
                app.AllCellRecMax = max(app.AllCellRec,[],3);
                %2D maximum of entire release and recovery movies
                app.AllCellMax = max(app.AllCellRelMax,app.AllCellRecMax);
                
                app.ImgN=cat(3,app.ImgNRel,app.ImgNRec);
                app.ImgC1=cat(3,app.ImgCRel,app.ImgCRec);
                app.ImgM=cat(3,app.ImgMRel,app.ImgMRec);
                
                app.app_stage =3;
                app.interval_stage = 1;
                app.interval_counter = 1;
                
                app.TabGroup.SelectedTab = app.CompartmentviewingpercentilesTab;

             elseif app.app_stage ==1
                 info_label = 'Error Messages';
                 lsm_type_error = ['Error. Complete channel allocation and select' newline '"Accept Channels and Crop Image".'];
                 app.lsm_flag = 0;
                 app.error_counter = 1;
                 info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                 app.OutputinformationLabel.Text = info_label;
                 app.OutputinformationLabel.BackgroundColor = 'k';
             elseif app.app_stage ==0
                 info_label = 'Error Messages';
                 lsm_type_error = ['Error. Complete I/O correctly.'];
                 app.lsm_flag = 0;
                 app.error_counter = 1;
                 info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                 app.OutputinformationLabel.Text = info_label;
                 app.OutputinformationLabel.BackgroundColor = 'k';
             end
        end

        % Value changed function: LoadNextIterationButton
        function LoadNextIterationButtonValueChanged(app, event)
            value = app.LoadNextIterationButton.Value;
            
            if app.app_stage == 3
                app.OutputinformationLabel.BackgroundColor = 'none';
                if app.interval_counter == 1
                    app.nuclear_slider_prctile_flag = 0;
                    app.mito_slider_prctile_flag = 0;
                    app.regions = 1:(app.length-1)/app.intervals(app.interval_stage):app.length;%Start point of each region. 
                    app.regions = round(app.regions)
                    app.Midpoints=1+(app.length-1)/app.intervals(app.interval_stage)/2:(app.length-1)/app.intervals(app.interval_stage):app.length;
                    app.Midpoints=round(app.Midpoints);
               
                    app.AllCellRegionMax=zeros([size(app.AllCellMax),app.intervals(app.interval_stage)]);
                    app.RegionNMax=zeros([size(app.AllCellMax),app.intervals(app.interval_stage)]);
                    app.RegionMMax=zeros([size(app.AllCellMax),app.intervals(app.interval_stage)]);
                    app.RegionC1Max=zeros([size(app.AllCellMax),app.intervals(app.interval_stage)]);
                    app.MedianRegion=zeros([size(app.AllCellMax),app.intervals(app.interval_stage)]);
               
                    for J=1:app.intervals(app.interval_stage)
                        %Median intensity for maximum intensities over time
                        app.MedianRegion(:,:,J)=uint16(median(max(max(app.ImgN(:,:,app.regions(J):app.regions(J+1)-1),app.ImgC1(:,:,app.regions(J):app.regions(J+1)-1)),app.ImgM(:,:,app.regions(J):app.regions(J+1)-1)),3));
                        %Maximum intensity of nucleus in that region
                        app.RegionNMax(:,:,J)=max(app.ImgN(:,:,app.regions(J):app.regions(J+1)-1),[],3);
                        %Maximum intesnity of cytoplasm in that region
                        app.RegionC1Max(:,:,J)=max(app.ImgC1(:,:,app.regions(J):app.regions(J+1)-1),[],3);
                        %Maximum intensity of mitochondria in that region
                        app.RegionMMax(:,:,J)=max(app.ImgM(:,:,app.regions(J):app.regions(J+1)-1),[],3);
                        %Maximum intensity of entire cell over that time region
                        app.AllCellRegionMax(:,:,J)=max(app.RegionMMax(:,:,J),max(app.RegionNMax(:,:,J),app.RegionC1Max(:,:,J)));
%                         
                    end

                    %app.MedianRegionInput=app.MedianRegion;
                    %app.InputImgCRegion=app.RegionC1Max;
                    %app.InputImgNRegion=app.RegionNMax;
                    
                    app.nuclear_prctile=0;
                    app.mito_prctile=0;
                    app.nuclear_region_index=min(1,length(app.regions)-1);
                    app.mito_region_index=min(1,length(app.regions)-1);
                    nuc_image_percentile=uint16(prctile(app.ImgN(:,:,app.regions(app.nuclear_region_index):app.regions(app.nuclear_region_index+1)-1),app.nuclear_prctile,3));
                    mito_image_percentile=uint16(prctile(app.ImgM(:,:,app.regions(app.mito_region_index):app.regions(app.mito_region_index+1)-1),app.mito_prctile,3));
                    
                    title(app.UIAxes7, []);
                    xlabel(app.UIAxes7, []);
                    ylabel(app.UIAxes7, []);
                    app.UIAxes7.XAxis.TickLabels = {};
                    app.UIAxes7.YAxis.TickLabels = {};
                    % Display image and stretch to fill axes
                    I = imagesc(nuc_image_percentile, 'Parent', app.UIAxes7, ...
                        'XData', [1 app.UIAxes7.Position(3)], ...
                        'YData', [1 app.UIAxes7.Position(4)]);
                    % Set limits of axes
                    app.UIAxes7.XLim = [0 I.XData(2)];
                    app.UIAxes7.YLim = [0 I.YData(2)];
                    axis(app.UIAxes7, 'equal');
                    
                    
                    title(app.UIAxes7_2, []);
                    xlabel(app.UIAxes7_2, []);
                    ylabel(app.UIAxes7_2, []);
                    app.UIAxes7_2.XAxis.TickLabels = {};
                    app.UIAxes7_2.YAxis.TickLabels = {};
                    % Display image and stretch to fill axes
                    I = imagesc(mito_image_percentile, 'Parent', app.UIAxes7_2, ...
                        'XData', [1 app.UIAxes7_2.Position(3)], ...
                        'YData', [1 app.UIAxes7_2.Position(4)]);
                    % Set limits of axes
                    app.UIAxes7_2.XLim = [0 I.XData(2)];
                    app.UIAxes7_2.YLim = [0 I.YData(2)];
                    axis(app.UIAxes7_2, 'equal');
                    
                    app.app_stage = 4;
                    
                else
                    info_label = 'Error Messages';
                    lsm_type_error = ['Error. Complete stages in the previous interval selection first.'];
                    app.error_counter = 1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    app.OutputinformationLabel.BackgroundColor = 'k';
                end
            elseif app.app_stage < 3
                info_label = 'Error Messages';
                lsm_type_error = ['Error. Complete stages in the Channel Selection tab.'];
                app.error_counter = 1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                app.OutputinformationLabel.BackgroundColor = 'k';
            elseif app.app_stage == 5 
                info_label = 'Error Messages';
                lsm_type_error = ['Error. Complete stages in Cell boundary cropping tab.'];
                app.error_counter = 1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                app.OutputinformationLabel.BackgroundColor = 'k';
            elseif app.app_stage == 6 
                info_label = 'Error Messages';
                lsm_type_error = ['Error. Complete stages in Cell thresholding tab.'];
                app.error_counter = 1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                app.OutputinformationLabel.BackgroundColor = 'k';
            elseif app.app_stage == 7 
                info_label = 'Error Messages';
                lsm_type_error = ['Error. Complete stages in Compartment thresholding tab.'];
                app.error_counter = 1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                app.OutputinformationLabel.BackgroundColor = 'k';
            
            end               


%                 %This needs putting somewhere else now
%                 if app.interval_counter == app.intervals(app.interval_stage)
%                     app.interval_stage = app.interval_stage + 1;
%                     app.interval_counter = 1;
%                 else
%                     app.interval_counter = app.interval_counter +1;
%                 end
%                 
%                 if app.interval_counter == 1
%                    
%                 end


        end

        % Value changing function: FramewindowSlider
        function FramewindowSliderValueChanging(app, event)
            changingValue = uint8(event.Value);
            if app.app_stage == 4
                app.OutputinformationLabel.BackgroundColor = 'none';
                if app.interval_counter == 1

                    app.nuclear_region_index=min(changingValue,length(app.regions)-1);
                    nuc_image_percentile=uint16(prctile(app.ImgN(:,:,app.regions(app.nuclear_region_index):app.regions(app.nuclear_region_index+1)-1),app.nuclear_prctile,3));
                        
                    title(app.UIAxes7, []);
                    xlabel(app.UIAxes7, []);
                    ylabel(app.UIAxes7, []);
                    app.UIAxes7.XAxis.TickLabels = {};
                    app.UIAxes7.YAxis.TickLabels = {};
                    % Display image and stretch to fill axes
                    I = imagesc(nuc_image_percentile, 'Parent', app.UIAxes7, ...
                        'XData', [1 app.UIAxes7.Position(3)], ...
                        'YData', [1 app.UIAxes7.Position(4)]);
                    % Set limits of axes
                    app.UIAxes7.XLim = [0 I.XData(2)];
                    app.UIAxes7.YLim = [0 I.YData(2)];
                    axis(app.UIAxes7, 'equal');
                    
                else
                    info_label = 'Error Messages';
                    lsm_type_error = ['Error. Complete stages in the previous interval selection first.'];
                    app.error_counter = 1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    app.OutputinformationLabel.BackgroundColor = 'k';
                end
            elseif app.app_stage == 3
                info_label = 'Error Messages';
                lsm_type_error = ['Error. Click "Load Next Iteration."'];
                app.error_counter = 1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                app.OutputinformationLabel.BackgroundColor = 'k';    
            elseif app.app_stage < 3
                info_label = 'Error Messages';
                lsm_type_error = ['Error. Complete stages in the Channel Selection tab.'];
                app.error_counter = 1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                app.OutputinformationLabel.BackgroundColor = 'k';
            elseif app.app_stage == 5 
                info_label = 'Error Messages';
                lsm_type_error = ['Error. Complete stages in Cell boundary cropping tab.'];
                app.error_counter = 1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                app.OutputinformationLabel.BackgroundColor = 'k';
            elseif app.app_stage == 7 
                info_label = 'Error Messages';
                lsm_type_error = ['Error. Complete stages in Cell thresholding tab.'];
                app.error_counter = 1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                app.OutputinformationLabel.BackgroundColor = 'k';
            elseif app.app_stage == 7 
                info_label = 'Error Messages';
                lsm_type_error = ['Error. Complete stages in Compartment thresholding tab.'];
                app.error_counter = 1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                app.OutputinformationLabel.BackgroundColor = 'k';
            
            end  
     
        end

        % Value changing function: FramewindowSlider_2
        function FramewindowSlider_2ValueChanging(app, event)
            changingValue = uint8(event.Value);
            if app.app_stage == 4
                app.OutputinformationLabel.BackgroundColor = 'none';
                if app.interval_counter == 1
                    app.mito_region_index=min(changingValue,length(app.regions)-1);
                    mito_image_percentile=uint16(prctile(app.ImgM(:,:,app.regions(app.mito_region_index):app.regions(app.mito_region_index+1)-1),app.mito_prctile,3));
                                       
                    title(app.UIAxes7_2, []);
                    xlabel(app.UIAxes7_2, []);
                    ylabel(app.UIAxes7_2, []);
                    app.UIAxes7_2.XAxis.TickLabels = {};
                    app.UIAxes7_2.YAxis.TickLabels = {};
                    % Display image and stretch to fill axes
                    I = imagesc(mito_image_percentile, 'Parent', app.UIAxes7_2, ...
                        'XData', [1 app.UIAxes7_2.Position(3)], ...
                        'YData', [1 app.UIAxes7_2.Position(4)]);
                    % Set limits of axes
                    app.UIAxes7_2.XLim = [0 I.XData(2)];
                    app.UIAxes7_2.YLim = [0 I.YData(2)];
                    axis(app.UIAxes7_2, 'equal');
                else
                    info_label = 'Error Messages';
                    lsm_type_error = ['Error. Complete stages in the previous interval selection first.'];
                    app.error_counter = 1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    app.OutputinformationLabel.BackgroundColor = 'k';
                end
            elseif app.app_stage == 3
                info_label = 'Error Messages';
                lsm_type_error = ['Error. Click "Load Next Iteration."'];
                app.error_counter = 1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                app.OutputinformationLabel.BackgroundColor = 'k';                
            elseif app.app_stage < 3
                info_label = 'Error Messages';
                lsm_type_error = ['Error. Complete stages in the Channel Selection tab.'];
                app.error_counter = 1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                app.OutputinformationLabel.BackgroundColor = 'k';
            elseif app.app_stage == 5 
                info_label = 'Error Messages';
                lsm_type_error = ['Error. Complete stages in Cell boundary cropping tab.'];
                app.error_counter = 1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                app.OutputinformationLabel.BackgroundColor = 'k';
            elseif app.app_stage == 6 
                info_label = 'Error Messages';
                lsm_type_error = ['Error. Complete stages in Cell thresholding tab.'];
                app.error_counter = 1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                app.OutputinformationLabel.BackgroundColor = 'k';
            elseif app.app_stage == 7 
                info_label = 'Error Messages';
                lsm_type_error = ['Error. Complete stages in Compartment thresholding tab.'];
                app.error_counter = 1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                app.OutputinformationLabel.BackgroundColor = 'k';
            
            end              
            
        end

        % Value changing function: PercentageSlider
        function PercentageSliderValueChanging(app, event)
            changingValue = event.Value;
            if app.app_stage == 4
                app.OutputinformationLabel.BackgroundColor = 'none';
                if app.interval_counter == 1
                    app.nuclear_slider_prctile_flag = 1;
                                       
                    app.nuclear_prctile=changingValue;
            
                    nuc_image_percentile=uint16(prctile(app.ImgN(:,:,app.regions(app.nuclear_region_index):app.regions(app.nuclear_region_index+1)-1),app.nuclear_prctile,3));
                                
                    title(app.UIAxes7, []);
                    xlabel(app.UIAxes7, []);
                    ylabel(app.UIAxes7, []);
                    app.UIAxes7.XAxis.TickLabels = {};
                    app.UIAxes7.YAxis.TickLabels = {};
                    % Display image and stretch to fill axes
                    I = imagesc(nuc_image_percentile, 'Parent', app.UIAxes7, ...
                        'XData', [1 app.UIAxes7.Position(3)], ...
                        'YData', [1 app.UIAxes7.Position(4)]);
                    % Set limits of axes
                    app.UIAxes7.XLim = [0 I.XData(2)];
                    app.UIAxes7.YLim = [0 I.YData(2)];
                    axis(app.UIAxes7, 'equal');
                else
                    info_label = 'Error Messages';
                    lsm_type_error = ['Error. Complete stages in the previous interval selection first.'];
                    app.error_counter = 1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    app.OutputinformationLabel.BackgroundColor = 'k';
                end
            elseif app.app_stage == 3
                info_label = 'Error Messages';
                lsm_type_error = ['Error. Click "Load Next Iteration."'];
                app.error_counter = 1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                app.OutputinformationLabel.BackgroundColor = 'k';
            elseif app.app_stage < 3
                info_label = 'Error Messages';
                lsm_type_error = ['Error. Complete stages in the Channel Selection tab.'];
                app.error_counter = 1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                app.OutputinformationLabel.BackgroundColor = 'k';
            elseif app.app_stage == 5 
                info_label = 'Error Messages';
                lsm_type_error = ['Error. Complete stages in Cell boundary cropping tab.'];
                app.error_counter = 1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                app.OutputinformationLabel.BackgroundColor = 'k';
            elseif app.app_stage == 6 
                info_label = 'Error Messages';
                lsm_type_error = ['Error. Complete stages in Cell thresholding tab.'];
                app.error_counter = 1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                app.OutputinformationLabel.BackgroundColor = 'k';
            elseif app.app_stage == 7 
                info_label = 'Error Messages';
                lsm_type_error = ['Error. Complete stages in Compartment thresholding tab.'];
                app.error_counter = 1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                app.OutputinformationLabel.BackgroundColor = 'k';
            
            end                       
        end

        % Value changing function: PercentageSlider_2
        function PercentageSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            if app.app_stage == 4
                app.OutputinformationLabel.BackgroundColor = 'none';
                if app.interval_counter == 1
                    app.mito_slider_prctile_flag = 1;
                    app.mito_prctile = changingValue;
                    mito_image_percentile = uint16(prctile(app.ImgM(:,:,app.regions(app.mito_region_index):app.regions(app.mito_region_index+1)-1),app.mito_prctile,3));
                                       
                    title(app.UIAxes7_2, []);
                    xlabel(app.UIAxes7_2, []);
                    ylabel(app.UIAxes7_2, []);
                    app.UIAxes7_2.XAxis.TickLabels = {};
                    app.UIAxes7_2.YAxis.TickLabels = {};
                    % Display image and stretch to fill axes
                    I = imagesc(mito_image_percentile, 'Parent', app.UIAxes7_2, ...
                        'XData', [1 app.UIAxes7_2.Position(3)], ...
                        'YData', [1 app.UIAxes7_2.Position(4)]);
                    % Set limits of axes
                    app.UIAxes7_2.XLim = [0 I.XData(2)];
                    app.UIAxes7_2.YLim = [0 I.YData(2)];
                    axis(app.UIAxes7_2, 'equal');
                else
                    info_label = 'Error Messages';
                    lsm_type_error = ['Error. Complete stages in the previous interval selection first.'];
                    app.error_counter = 1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    app.OutputinformationLabel.BackgroundColor = 'k';
                end
            elseif app.app_stage == 3
                info_label = 'Error Messages';
                lsm_type_error = ['Error. Click "Load Next Iteration."'];
                app.error_counter = 1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                app.OutputinformationLabel.BackgroundColor = 'k';
            elseif app.app_stage < 3
                info_label = 'Error Messages';
                lsm_type_error = ['Error. Complete stages in the Channel Selection tab.'];
                app.error_counter = 1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                app.OutputinformationLabel.BackgroundColor = 'k';
            elseif app.app_stage == 5 
                info_label = 'Error Messages';
                lsm_type_error = ['Error. Complete stages in Cell boundary cropping tab.'];
                app.error_counter = 1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                app.OutputinformationLabel.BackgroundColor = 'k';
            elseif app.app_stage == 6 
                info_label = 'Error Messages';
                lsm_type_error = ['Error. Complete stages in Cell thresholding tab.'];
                app.error_counter = 1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                app.OutputinformationLabel.BackgroundColor = 'k';
            elseif app.app_stage == 7 
                info_label = 'Error Messages';
                lsm_type_error = ['Error. Complete stages in Compartment thresholding tab.'];
                app.error_counter = 1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                app.OutputinformationLabel.BackgroundColor = 'k';
            
            end     
        end

        % Value changed function: 
        % AcceptPercentilesforImageViewingButton
        function AcceptPercentilesforImageViewingButtonValueChanged(app, event)
            value = app.AcceptPercentilesforImageViewingButton.Value;
            proceed_to_cropping_flag = 1;
            app.error_counter = 0;
            info_label = 'Error Messages';
            if app.app_stage == 4
                if app.interval_counter ~= 1
                    lsm_type_error = ['Error. Complete stages in the previous interval selection first.'];
                    app.error_counter = app.error_counter+ 1;
                    proceed_to_cropping_flag = 0;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    app.OutputinformationLabel.BackgroundColor = 'k';
                end
            
                if app.nuclear_slider_prctile_flag ~= 1
                    lsm_type_error = ['Error. Select nuclear imaging percentile using the slider.'];
                    app.error_counter = app.error_counter+ 1;
                    proceed_to_cropping_flag = 0;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    app.OutputinformationLabel.BackgroundColor = 'k';
                end 
                
                if app.mito_slider_prctile_flag ~= 1
                    lsm_type_error = ['Error. Select mitochondrial imaging percentile using the slider.'];
                    app.error_counter = app.error_counter+ 1;
                    proceed_to_cropping_flag = 0;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    app.OutputinformationLabel.BackgroundColor = 'k';
                end
                
                if app.interval_counter == 1 && app.nuclear_slider_prctile_flag == 1 && app.mito_slider_prctile_flag
                    
                    app.app_stage = 5;


                    title(app.UIAxes6, []);
                    xlabel(app.UIAxes6, []);
                    ylabel(app.UIAxes6, []);
                    app.UIAxes6.XAxis.TickLabels = {};
                    app.UIAxes6.YAxis.TickLabels = {};
                    % Display image and stretch to fill axes
                    image_input = zeros(size(app.AllCellMax));
                    image_input(:,:) = app.AllCellRegionMax(:,:,app.interval_counter);
                    I = imagesc(image_input, 'Parent', app.UIAxes6, ...
                        'XData', [1 app.UIAxes6.Position(3)], ...
                        'YData', [1 app.UIAxes6.Position(4)]);
                    % Set limits of axes
                    app.UIAxes6.XLim = [0 I.XData(2)];
                    app.UIAxes6.YLim = [0 I.YData(2)];
                    axis(app.UIAxes6, 'equal');


                    title(app.UIAxes6_2, []);
                    xlabel(app.UIAxes6_2, []);
                    ylabel(app.UIAxes6_2, []);
                    app.UIAxes6_2.XAxis.TickLabels = {};
                    app.UIAxes6_2.YAxis.TickLabels = {};
                    % Display image and stretch to fill axes
                    image_input = zeros(size(app.AllCellMax));
                    image_input(:,:) = app.RegionC1Max(:,:,app.interval_counter);
                    I = imagesc(image_input, 'Parent', app.UIAxes6_2, ...
                        'XData', [1 app.UIAxes6_2.Position(3)], ...
                        'YData', [1 app.UIAxes6_2.Position(4)]);
                    % Set limits of axes
                    app.UIAxes6_2.XLim = [0 I.XData(2)];
                    app.UIAxes6_2.YLim = [0 I.YData(2)];
                    axis(app.UIAxes6_2, 'equal');
                    
                    if app.intervals(app.interval_stage) == 1
                        title(app.UIAxes6_3, []);
                        xlabel(app.UIAxes6_3, []);
                        ylabel(app.UIAxes6_3, []);
                        app.UIAxes6_3.XAxis.TickLabels = {};
                        app.UIAxes6_3.YAxis.TickLabels = {};
                        % Display image and stretch to fill axes
                        image_input = zeros(size(app.AllCellMax));
                        image_input(:,:) = app.RegionNMax(:,:,app.interval_counter);
                        I = imagesc(image_input, 'Parent', app.UIAxes6_3, ...
                            'XData', [1 app.UIAxes6_3.Position(3)], ...
                            'YData', [1 app.UIAxes6_3.Position(4)]);
                        % Set limits of axes
                        app.UIAxes6_3.XLim = [0 I.XData(2)];
                        app.UIAxes6_3.YLim = [0 I.YData(2)];
                        axis(app.UIAxes6_3, 'equal');
                    else
                        title(app.UIAxes6_3, []);
                        xlabel(app.UIAxes6_3, []);
                        ylabel(app.UIAxes6_3, []);
                        app.UIAxes6_3.XAxis.TickLabels = {};
                        app.UIAxes6_3.YAxis.TickLabels = {};
                        % Display image and stretch to fill axes
                        I = imagesc(app.AllCellMax, 'Parent', app.UIAxes6_3, ...
                            'XData', [1 app.UIAxes6_3.Position(3)], ...
                            'YData', [1 app.UIAxes6_3.Position(4)]);
                        % Set limits of axes
                        app.UIAxes6_3.XLim = [0 I.XData(2)];
                        app.UIAxes6_3.YLim = [0 I.YData(2)];
                        axis(app.UIAxes6_3, 'equal');
                        
                    end
                    
                    
                    app.TabGroup.SelectedTab = app.CellboundarycropTab;
                end
            elseif app.app_stage == 3
                lsm_type_error = ['Error. Click "Load Next Iteration."'];
                app.error_counter = app.error_counter+ 1;
                proceed_to_cropping_flag = 0;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                app.OutputinformationLabel.BackgroundColor = 'k';
            elseif app.app_stage < 3
                lsm_type_error = ['Error. Complete stages in the Channel Selection tab.'];
                app.error_counter = app.error_counter+ 1;
                proceed_to_cropping_flag = 0;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                app.OutputinformationLabel.BackgroundColor = 'k';
            elseif app.app_stage == 5 
                lsm_type_error = ['Error. Complete stages in Cell boundary cropping tab.'];
                app.error_counter = app.error_counter+ 1;
                proceed_to_cropping_flag = 0;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                app.OutputinformationLabel.BackgroundColor = 'k';
            elseif app.app_stage == 6 
                lsm_type_error = ['Error. Complete stages in Cell thresholding tab.'];
                app.error_counter = app.error_counter+ 1;
                proceed_to_cropping_flag = 0;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                app.OutputinformationLabel.BackgroundColor = 'k';
            elseif app.app_stage == 7 
                lsm_type_error = ['Error. Complete stages in Compartment thresholding tab.'];
                app.error_counter = app.error_counter+ 1;
                proceed_to_cropping_flag = 0;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                app.OutputinformationLabel.BackgroundColor = 'k';
            end


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ImageIOLabel = uilabel(app.LeftPanel);
            app.ImageIOLabel.Text = 'Image I/O';
            app.ReleasefileEditFieldLabel = uilabel(app.LeftPanel);
            app.ReleasefileEditFieldLabel.Text = 'Release file';
            app.ReleasefileEditField = uieditfield(app.LeftPanel, 'text');
            app.ReleasefileEditField.Value = 'C:\Documents\release.lsm';
            app.RecoveryfileEditFieldLabel = uilabel(app.LeftPanel);
            app.RecoveryfileEditFieldLabel.Text = 'Recovery file';
            app.RecoveryfileEditField = uieditfield(app.LeftPanel, 'text');
            app.RecoveryfileEditField.Value = 'C:\Documents\recovery.lsm';
            app.CellNameEditFieldLabel = uilabel(app.LeftPanel);
            app.CellNameEditFieldLabel.Text = 'Cell Name';
            app.CellNameEditField = uieditfield(app.LeftPanel, 'text');
            app.CellNameEditField.Value = 'cell_name';
            app.NoofintervalsLabel = uilabel(app.LeftPanel);
            app.NoofintervalsLabel.Text = 'No. of intervals';
            app.OKButton = uibutton(app.LeftPanel, 'state');
            app.OKButton.ValueChangedFcn = createCallbackFcn(app, @OKButtonValueChanged, true);
            app.OKButton.Text = 'OK';
            app.ConfirmSelectionLabel = uilabel(app.LeftPanel);
            app.ConfirmSelectionLabel.Text = 'Confirm Selection';
            app.OutputinformationLabel = uilabel(app.LeftPanel);
            app.OutputinformationLabel.Text = 'Output information';
            app.TextArea = uitextarea(app.LeftPanel);
            app.IntervalsselectedLabel = uilabel(app.LeftPanel);
            app.IntervalsselectedLabel.Text = 'Intervals selected: ';
            app.CenterPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.ChannelselectionTab = uitab(app.TabGroup);
            app.ChannelselectionTab.Title = 'Channel selection';
            app.Channel1Label = uilabel(app.ChannelselectionTab);
            app.Channel1Label.Text = '  Channel 1';
            app.FrameSliderLabel = uilabel(app.ChannelselectionTab);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.ChannelselectionTab);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.Channel1ButtonGroup = uibuttongroup(app.ChannelselectionTab);
            app.Channel1ButtonGroup.Title = 'Channel 1';
            app.NoneButton_5 = uiradiobutton(app.Channel1ButtonGroup);
            app.NoneButton_5.Text = 'None';
            app.NoneButton_5.Value = true;
            app.MitochondriaButton = uiradiobutton(app.Channel1ButtonGroup);
            app.MitochondriaButton.Text = 'Mitochondria';
            app.CytoplasmButton = uiradiobutton(app.Channel1ButtonGroup);
            app.CytoplasmButton.Text = 'Cytoplasm';
            app.NucleusButton = uiradiobutton(app.Channel1ButtonGroup);
            app.NucleusButton.Text = 'Nucleus';
            app.Channel2ButtonGroup = uibuttongroup(app.ChannelselectionTab);
            app.Channel2ButtonGroup.Title = 'Channel 2';
            app.NoneButton_6 = uiradiobutton(app.Channel2ButtonGroup);
            app.NoneButton_6.Text = 'None';
            app.NoneButton_6.Value = true;
            app.MitochondriaButton_2 = uiradiobutton(app.Channel2ButtonGroup);
            app.MitochondriaButton_2.Text = 'Mitochondria';
            app.CytoplasmButton_2 = uiradiobutton(app.Channel2ButtonGroup);
            app.CytoplasmButton_2.Text = 'Cytoplasm';
            app.NucleusButton_2 = uiradiobutton(app.Channel2ButtonGroup);
            app.NucleusButton_2.Text = 'Nucleus';
            app.Channel3ButtonGroup = uibuttongroup(app.ChannelselectionTab);
            app.Channel3ButtonGroup.Title = 'Channel 3';
            app.NoneButton_7 = uiradiobutton(app.Channel3ButtonGroup);
            app.NoneButton_7.Text = 'None';
            app.NoneButton_7.Value = true;
            app.MitochondriaButton_3 = uiradiobutton(app.Channel3ButtonGroup);
            app.MitochondriaButton_3.Text = 'Mitochondria';
            app.CytoplasmButton_3 = uiradiobutton(app.Channel3ButtonGroup);
            app.CytoplasmButton_3.Text = 'Cytoplasm';
            app.NucleusButton_3 = uiradiobutton(app.Channel3ButtonGroup);
            app.NucleusButton_3.Text = 'Nucleus';
            app.Channel4ButtonGroup = uibuttongroup(app.ChannelselectionTab);
            app.Channel4ButtonGroup.Title = 'Channel 4';
            app.NoneButton_8 = uiradiobutton(app.Channel4ButtonGroup);
            app.NoneButton_8.Text = 'None';
            app.NoneButton_8.Value = true;
            app.MitochondriaButton_4 = uiradiobutton(app.Channel4ButtonGroup);
            app.MitochondriaButton_4.Text = 'Mitochondria';
            app.CytoplasmButton_4 = uiradiobutton(app.Channel4ButtonGroup);
            app.CytoplasmButton_4.Text = 'Cytoplasm';
            app.NucleusButton_4 = uiradiobutton(app.Channel4ButtonGroup);
            app.NucleusButton_4.Text = 'Nucleus';
            app.AcceptChannelsandCropImageButton = uibutton(app.ChannelselectionTab, 'state');
            app.AcceptChannelsandCropImageButton.ValueChangedFcn = createCallbackFcn(app, @AcceptChannelsandCropImageButtonValueChanged, true);
            app.AcceptChannelsandCropImageButton.Text = 'Accept Channels and Crop Image';
            app.UIAxes = uiaxes(app.ChannelselectionTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.ChannelselectionTab);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.ChannelselectionTab);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes4 = uiaxes(app.ChannelselectionTab);
            title(app.UIAxes4, '')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.AcceptCropButton = uibutton(app.ChannelselectionTab, 'state');
            app.AcceptCropButton.ValueChangedFcn = createCallbackFcn(app, @AcceptCropButtonValueChanged, true);
            app.AcceptCropButton.Text = 'Accept Crop';
            app.Channel2Label = uilabel(app.ChannelselectionTab);
            app.Channel2Label.Text = '  Channel 2';
            app.Channel3Label = uilabel(app.ChannelselectionTab);
            app.Channel3Label.Text = '  Channel 3';
            app.Channel4Label = uilabel(app.ChannelselectionTab);
            app.Channel4Label.Text = '  Channel 4';
            app.CompartmentviewingpercentilesTab = uitab(app.TabGroup);
            app.CompartmentviewingpercentilesTab.Title = 'Compartment viewing percentiles';
            app.LoadNextIterationButton = uibutton(app.CompartmentviewingpercentilesTab, 'state');
            app.LoadNextIterationButton.ValueChangedFcn = createCallbackFcn(app, @LoadNextIterationButtonValueChanged, true);
            app.LoadNextIterationButton.Text = 'Load Next Iteration';
            app.UIAxes7 = uiaxes(app.CompartmentviewingpercentilesTab);
            title(app.UIAxes7, '')
            xlabel(app.UIAxes7, '')
            ylabel(app.UIAxes7, '')
            app.UIAxes7.XTick = [];
            app.UIAxes7.YTick = [];
            app.UIAxes7_2 = uiaxes(app.CompartmentviewingpercentilesTab);
            title(app.UIAxes7_2, '')
            xlabel(app.UIAxes7_2, '')
            ylabel(app.UIAxes7_2, '')
            app.UIAxes7_2.XTick = [];
            app.UIAxes7_2.YTick = [];
            app.PercentageSliderLabel = uilabel(app.CompartmentviewingpercentilesTab);
            app.PercentageSliderLabel.Text = 'Percentage';
            app.PercentageSlider = uislider(app.CompartmentviewingpercentilesTab);
            app.PercentageSlider.ValueChangingFcn = createCallbackFcn(app, @PercentageSliderValueChanging, true);
            app.PercentageSlider_2Label = uilabel(app.CompartmentviewingpercentilesTab);
            app.PercentageSlider_2Label.Text = 'Percentage';
            app.PercentageSlider_2 = uislider(app.CompartmentviewingpercentilesTab);
            app.PercentageSlider_2.ValueChangingFcn = createCallbackFcn(app, @PercentageSlider_2ValueChanging, true);
            app.NucleusLabel = uilabel(app.CompartmentviewingpercentilesTab);
            app.NucleusLabel.Text = 'Nucleus';
            app.MitochondriaLabel = uilabel(app.CompartmentviewingpercentilesTab);
            app.MitochondriaLabel.Text = 'Mitochondria';
            app.AcceptPercentilesforImageViewingButton = uibutton(app.CompartmentviewingpercentilesTab, 'state');
            app.AcceptPercentilesforImageViewingButton.ValueChangedFcn = createCallbackFcn(app, @AcceptPercentilesforImageViewingButtonValueChanged, true);
            app.AcceptPercentilesforImageViewingButton.Text = 'Accept Percentiles for Image Viewing';
            app.FramewindowSliderLabel = uilabel(app.CompartmentviewingpercentilesTab);
            app.FramewindowSliderLabel.Text = 'Frame window';
            app.FramewindowSlider = uislider(app.CompartmentviewingpercentilesTab);
            app.FramewindowSlider.Limits = [1 10];
            app.FramewindowSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.FramewindowSlider.ValueChangingFcn = createCallbackFcn(app, @FramewindowSliderValueChanging, true);
            app.FramewindowSlider.MinorTicks = [];
            app.FramewindowSlider.Value = 1;
            app.FramewindowSlider_2Label = uilabel(app.CompartmentviewingpercentilesTab);
            app.FramewindowSlider_2Label.Text = 'Frame window';
            app.FramewindowSlider_2 = uislider(app.CompartmentviewingpercentilesTab);
            app.FramewindowSlider_2.Limits = [1 10];
            app.FramewindowSlider_2.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.FramewindowSlider_2.ValueChangingFcn = createCallbackFcn(app, @FramewindowSlider_2ValueChanging, true);
            app.FramewindowSlider_2.MinorTicks = [];
            app.FramewindowSlider_2.Value = 1;
            app.CellboundarycropTab = uitab(app.TabGroup);
            app.CellboundarycropTab.Title = 'Cell boundary crop';
            app.UIAxes5 = uiaxes(app.CellboundarycropTab);
            title(app.UIAxes5, '')
            xlabel(app.UIAxes5, '')
            ylabel(app.UIAxes5, '')
            app.UIAxes5.XTick = [];
            app.UIAxes5.YTick = [];
            app.UIAxes6 = uiaxes(app.CellboundarycropTab);
            title(app.UIAxes6, '')
            xlabel(app.UIAxes6, '')
            ylabel(app.UIAxes6, '')
            app.UIAxes6.XTick = [];
            app.UIAxes6.YTick = [];
            app.UIAxes6_2 = uiaxes(app.CellboundarycropTab);
            title(app.UIAxes6_2, '')
            xlabel(app.UIAxes6_2, '')
            ylabel(app.UIAxes6_2, '')
            app.UIAxes6_2.XTick = [];
            app.UIAxes6_2.YTick = [];
            app.UIAxes6_3 = uiaxes(app.CellboundarycropTab);
            title(app.UIAxes6_3, '')
            xlabel(app.UIAxes6_3, '')
            ylabel(app.UIAxes6_3, '')
            app.UIAxes6_3.XTick = [];
            app.UIAxes6_3.YTick = [];
            app.ImageSelectionButtonGroup = uibuttongroup(app.CellboundarycropTab);
            app.ImageSelectionButtonGroup.Title = 'Image Selection';
            app.Image1Button = uitogglebutton(app.ImageSelectionButtonGroup);
            app.Image1Button.Text = 'Image 1';
            app.Image1Button.Value = true;
            app.Image2Button = uitogglebutton(app.ImageSelectionButtonGroup);
            app.Image2Button.Text = 'Image 2';
            app.Image3Button = uitogglebutton(app.ImageSelectionButtonGroup);
            app.Image3Button.Text = 'Image 3';
            app.ConfirmcropButton = uibutton(app.CellboundarycropTab, 'state');
            app.ConfirmcropButton.Text = 'Confirm crop';
            app.CellthresholdingTab = uitab(app.TabGroup);
            app.CellthresholdingTab.Title = 'Cell thresholding';
            app.CompartmentthresholdingTab = uitab(app.TabGroup);
            app.CompartmentthresholdingTab.Title = 'Compartment thresholding';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = opto_analyser_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end